classdef Final_TestBed < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HerramientadeCVparaRobticaSwarmUIFigure  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        CalibracinTab                   matlab.ui.container.Tab
        btnCalibrar                     matlab.ui.control.Button
        btnConnectar                    matlab.ui.control.Button
        dropdownCamara                  matlab.ui.control.DropDown
        lblCamara                       matlab.ui.control.Label
        btnSeleccionManual              matlab.ui.control.Button
        imgLogo2                        matlab.ui.control.Image
        btnSaveCalli                    matlab.ui.control.Button
        lblErrorCalli                   matlab.ui.control.Label
        btnLoadCalli                    matlab.ui.control.Button
        LongitudSpinnerLabel            matlab.ui.control.Label
        LongitudSpinner                 matlab.ui.control.Spinner
        AnchoSpinnerLabel               matlab.ui.control.Label
        AnchoSpinner                    matlab.ui.control.Spinner
        chkboxModoACO                   matlab.ui.control.CheckBox
        chkboxGrid                      matlab.ui.control.CheckBox
        EnviodeMapaButton               matlab.ui.control.Button
        CentrodeMesaCheckBox            matlab.ui.control.CheckBox
        OrigenyLabel                    matlab.ui.control.Label
        CentroYSpinner                  matlab.ui.control.Spinner
        OrigenxLabel                    matlab.ui.control.Label
        CentroXSpinner                  matlab.ui.control.Spinner
        TomadePoseTab                   matlab.ui.container.Tab
        btnPararTomadePose              matlab.ui.control.Button
        imgLogo1                        matlab.ui.control.Image
        btnTomadePose                   matlab.ui.control.Button
        lblDebug                        matlab.ui.control.Label
        chkboxBucle                     matlab.ui.control.CheckBox
        chkboxGuardarTiempos            matlab.ui.control.CheckBox
        chkboxDesplegarResultados       matlab.ui.control.CheckBox
        btnEnvodeDatos                  matlab.ui.control.Button
        chkboxEnvioentoma               matlab.ui.control.CheckBox
        GeneracindeCodigosTab           matlab.ui.container.Tab
        NmerodecdigoEditFieldLabel      matlab.ui.control.Label
        txtfldNumerodecodigo            matlab.ui.control.NumericEditField
        NombredelArchivoEditFieldLabel  matlab.ui.control.Label
        txtfldNombredelArchivo          matlab.ui.control.EditField
        btnGenerarCodigo                matlab.ui.control.Button
        btnGuardarCodigo                matlab.ui.control.Button
        lblNota                         matlab.ui.control.Label
        lblerrorGeneracion              matlab.ui.control.Label
        lblLogo3                        matlab.ui.control.Image
        Comunicacion                    matlab.ui.container.Tab
        DirreccinIPEditFieldLabel       matlab.ui.control.Label
        editfieldDirreccionIP           matlab.ui.control.EditField
        btnEliminarIP                   matlab.ui.control.Button
        IDdeRobotSpinnerLabel           matlab.ui.control.Label
        spnIDdeRobot                    matlab.ui.control.Spinner
        chkboxHabilitarComunicacionTCP  matlab.ui.control.CheckBox
        lblErroresCom                   matlab.ui.control.Label
        PuertogeneralEditFieldLabel     matlab.ui.control.Label
        editfieldPuerto                 matlab.ui.control.NumericEditField
        lblMensaje                      matlab.ui.control.Label
        chkboxHabilitarComunicacionUDP  matlab.ui.control.CheckBox
        IPsListBoxLabel                 matlab.ui.control.Label
        listboxIPs                      matlab.ui.control.ListBox
        btnAgregarIP                    matlab.ui.control.Button
        imgLogo2_2                      matlab.ui.control.Image
        PalabradeInicioEditFieldLabel   matlab.ui.control.Label
        editfieldPalabradeInicio        matlab.ui.control.EditField
        DebugTab                        matlab.ui.container.Tab
        chkboxDebugconImagenes          matlab.ui.control.CheckBox
        ImagenDropDownLabel             matlab.ui.control.Label
        dropdownImagen                  matlab.ui.control.DropDown
        ExtensinEditFieldLabel          matlab.ui.control.Label
        txtfldExtension                 matlab.ui.control.EditField
        chkboxMostrarProcesoMascara     matlab.ui.control.CheckBox
        chkboxMostrarProcesoPose        matlab.ui.control.CheckBox
        ValordePivoteEditFieldLabel     matlab.ui.control.Label
        editfieldValordePivote          matlab.ui.control.NumericEditField
        ValordeGrisEditFieldLabel       matlab.ui.control.Label
        editfieldValordeGris            matlab.ui.control.NumericEditField
        imgLogo2_3                      matlab.ui.control.Image
        ValorCircularidadACOEditFieldLabel  matlab.ui.control.Label
        editfieldValorCircularidadACO   matlab.ui.control.NumericEditField
        readeMuestreoEditFieldLabel     matlab.ui.control.Label
        editfieldAreadeMuestreo         matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        nombreCamara = '';      % Nombre de la cámara web a utilizar
        camara;                 % Cámara a utilizar
        tform;                  % Transformada de la mesa para el área desada
        outviewref;             % Formato de salida luego de transformada
        funcionando = false;    % Variable que controla el bucle de la toma de pose
        handleCodigo;           % Handle de figura para el despliegue de código
        handlePose;             % Handle de figura para el despliegue de pose
        manual_points;          % Puntos si se selecciona calibración manual
        manual = false;         % Variable que define si se seleccionó calibración manual
        obstacleMask;           % Imagen de la máscara de objetos
        obstacleMask2;
        centroidsMod;
        listaIPs = containers.Map('KeyType','double','ValueType','char');
        u;
        mapa;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Al inicializar, conseguir listado de camaras disponibles y
            % actualizar el dropdown de camaras
            camList = webcamlist;
            app.dropdownCamara.Items = camList';
        end

        % Value changed function: dropdownCamara
        function CamaraDropDownValueChanged(app, event)
            % Cada vez que cambie el dropdown de camara conseguri el valor
            % actual
            app.nombreCamara = app.dropdownCamara.Value;
            % Conseguir listado de camaras disponibles para actualizar
            camList = webcamlist;
            app.dropdownCamara.Items = camList';
            % Habilitar boton de conectar
            app.btnConnectar.Enable = true;
        end

        % Button pushed function: btnConnectar
        function btnConnectarPushed(app, event)
            % Leer valor de camara seleccionada
            app.camara =  webcam(app.nombreCamara);
            % Obtener resoluciones disponibles
            resolutions = app.camara.AvailableResolutions;
            % Utilizar la resolucion maxima
            app.camara.Resolution = string(resolutions(end));
            % Habilitar botones de calibracion
            app.btnCalibrar.Enable = true;
            app.btnSeleccionManual.Enable = true;
        end

        % Button pushed function: btnCalibrar
        function btnCalibrarPushed(app, event)
            %% Obstaculos
            
            if(app.chkboxModoACO.Value)
                % Leer imagen o tomar captura de camara
                if(app.chkboxDebugconImagenes.Value)
                    I = imread(app.dropdownImagen.Value);
                else
                    I = snapshot(app.camara);
                end
                % Aplicar transformacion
                Itrans = imwarp(I, app.tform, 'OutputView', app.outviewref);
                % Conversion a escala de grises
                CalliBW = rgb2gray(Itrans);
                % Filtro canny y relleno de resultado
                Canny = edge(CalliBW, 'Canny', 0.5);
                % Area minima y proporcion area/perimetro minimos para
                % tomar en consideracion
                area_min = 50; area_per_proportion = 1.25;
                filledImage = imfill(Canny, 'holes');
                
                %% Limpiado de imagen
                % Conseguir areas, perimetros y lista de pixeles
                s = regionprops(filledImage, 'Area', 'Perimeter', 'PixelIdxList', 'PixelList');
                
                % Concatenar de manera conveniente
                areas = cat(1, s.Area);
                perimeters = cat(1, s.Perimeter);
                pixellists = {s.PixelList};
                
                % Contornos con proporcion area/perimetro menor al
                % estipulado o area menor a la requerida se eliminan (para
                % quitar puntos o lineas no deseadas)
                for i = 1:length(areas)
                    if(areas(i)/perimeters(i) < area_per_proportion | areas(i) < area_min)
                        pixels = cell2mat(pixellists(i));
                        for i2 = 1:size(pixels,1)
                            filledImage(pixels(i2,2), pixels(i2,1)) = 0;
                        end
                    end
                end
                
                % Encontrar circulos en imagen.
                centroids = imfindcircles(filledImage,[1, 30], 'Sensitivity', 0.5);
                imshow(filledImage)
                app.obstacleMask2 = filledImage;
                for i = 1:size(centroids, 1)
                    viscircles(centroids(i,:), 10,'Color','Blue');
                    text(centroids(i,1),centroids(i,2),string(i));
                end
                
                %% Ordenar grid
                % Redondear ya que asi se requeria en el algoritmo ACO
                centroidsAjustados = floor(centroids);
                % Tomar valores en X primero
                valoresX = centroidsAjustados(:,1);
                
                % Ordenar valores respecto a coordenada en X
                [~, centroidsOrdenados] = dependentSort(valoresX,centroidsAjustados,'ascend');
                
                % Mapa y contadores iniciales
                app.mapa = containers.Map();
                x = 1;
                y = 1;
                % Tamano del mapa a utilizar
                mapSize = floor(sqrt(size(centroidsOrdenados,2)))
                % Lista de centroides para ordenar en Y
                centroids2 = [];
                valoresY = [];
                % Ordenar en Y y llenar variable de mapa
                for i = 1:size(centroidsOrdenados,2)
                    % Agregar centroides a lista y tomar valores en Y de
                    % los mismos
                    centroids2 = [centroids2;centroidsOrdenados(:, i)'];
                    valoresY = [valoresY; centroidsOrdenados(2, i)];
                    if(x == mapSize)
                        % Ordenar respecto a valores en Y
                        [~, centroidsYOrdenados] = dependentSort(valoresY,centroids2,'ascend');
                        % Llenar fila del mapa en orden respecto a valores
                        % en Y
                        for x = 1:mapSize
                            ID = strcat(string(x), string(y));
                            app.mapa(ID) = centroidsYOrdenados(:,x)';
                        end
                        % Reinicio de variables
                        valoresY = [];
                        centroids2 = [];
                        x = 0;
                        % Mover a la siguiente fila
                        y = y + 1;
                    end
                    % Mover a la siguiente columna
                    x = x + 1;
                end
                
                % Visualizacion de mapa con orden de nodos
                figure(2)
                imshow(filledImage)
                for y = 1:mapSize
                    for x = 1:mapSize
                        ID = strcat(string(x), string(y));          % ID en formato 'xy' ej: '34'
                        centroid = app.mapa(ID);                    % Centroide asociado al ID
                        viscircles(centroid, 10,'Color','Blue');    % Dibujar ciruclo en centro
                        text(centroid(1),centroid(2),ID);           % Colocar texto de ID de centroide
                    end
                end
                app.EnviodeMapaButton.Enable = true;
                
            % Si solo se buscan obstaculos
            else
                % Leer imagen o tomar captura de camara
                if(app.chkboxDebugconImagenes.Value)
                    I = imread(app.dropdownImagen.Value);
                else
                    I = snapshot(app.camara);
                end
                % Puntos para la transfomracion
                fixed_points=[0 0; size(I,2) 0; size(I,2) size(I,1); 0 size(I,1)];
                moving_points = [];
                % Si se hizo una seleccion manual, usar esos puntos
                if(app.manual)
                    moving_points = app.manual_points;
                    thresh = 0.5;
                % De lo contrario, encontrar las esquinas y ordenarlas para que
                % coincidan con las fixed_points
                else
                    [corners, ~, ~, thresh] = findCorners(I)
                    
                    fixed_points=[0 0; size(I,2) 0; size(I,2) size(I,1); 0 size(I,1)]; %puntos iniciales en la imagen original
                    [moving_points] = orderCorners(corners(1:4,:), fixed_points);
                end
                % Crear la transformada proyectiva
                app.tform = fitgeotrans(moving_points,fixed_points,'projective');
                % Referencia de tamano para la transformacion
                app.outviewref = imref2d(size(I));
                % Aplicar transformacion
                Itrans = imwarp(I, app.tform, 'OutputView', app.outviewref);
                % Convertir a escala de grises
                Icanny = rgb2gray(I);
                % Filtrado Canny para mostrar en caso de que se desee
                Icanny = edge(Icanny,'Canny',thresh);
                % Mostrar imagenes del proceso de calibracion con proposito de
                % depuracion
                if(app.chkboxMostrarProcesoMascara.Value)
                    figure(18);clf
                    subplot(2, 2, 1)
                    imshow(I)
                    title('Imagen orignal')
                    subplot(2, 2, 2)
                    imshow(Icanny)
                    title('Imagen con filtro de bordes aplicado')
                    subplot(2, 2, 3)
                    imshow(I)
                    viscircles(corners, 10*ones(1,length(corners)),'EdgeColor','b');
                    title('Imagen original con marcadores circulares encontrados')
                    subplot(2, 2, 4)
                    imshow(Itrans)
                    title('Imagen calibrada')
                    sgtitle('Calibración de imagen')
                end
                % Conversion a escala de grises
                CalliBW = rgb2gray(Itrans);
                % Filtro Canny
                CalliBW = edge(CalliBW,'Canny',0.1);
                % Rellenado de contornos cerrados de imagen
                filledImage = imfill(CalliBW, 'holes');
                % Dilatado de imagen de 10px
                se = strel('line',10,10);
                filledImage = imdilate(filledImage,se);
                
                % Hacer la mascara el complemento
                app.obstacleMask = imcomplement(filledImage);
                % Despliegue de resultados de calibracion
                figure(1); clf
                subplot(1, 2, 1)
                imshow(Itrans)
                title('Imagen Calibrada','FontWeight','normal')
                subplot(1, 2, 2)
                imshow(app.obstacleMask)
                title('Máscara de Obstáculos encontrados','FontWeight','normal')
                sgtitle('Detección de obstáculos')
                % Habilitar botones para guardar datos y comenzar a tomar pose
                app.btnTomadePose.Enable = true;
                app.btnSaveCalli.Enable = true;
                app.chkboxModoACO.Enable = true;
            end
        end

        % Button pushed function: btnTomadePose
        function btnTomadePosePushed(app, event)
            app.btnPararTomadePose.Enable = true;   % Habilitar el boton para detener funcionamiento
            app.funcionando = true;                 % Valor para saber si se continua el ciclo o se deja de iterar
            pivotPropThresh = app.editfieldValordePivote.Value; % Umbral para clasificar el pivote
            highThresh = app.editfieldValordeGris.Value;        % Umbral para distingir gris y negro
            rad = 5*ones(9, 1);                     % Radios de circulos para imagenes
            area_per_proportion = 1.25;             % Proporcion de area/perimetro para distinguir contornos cerrados de sombras
            area_min = 50;                          % Area minima para clasificar como objeto
                        
            % Limpiar figura 1 para despliegue de resultados
            figure(1);clf;
            % Conseguir un handle para la figura (o mas bien los ejes)
            self.handlePose = gca;
            title('Pose de códigos','FontWeight','normal')
            % Hacer que los ejes sobreescriban lo que tienen
            set(self.handlePose,'NextPlot','replacechildren');
            while(app.funcionando)
                try
                    % Pausa para no sobrecargar el sistema 
                    pause(0.1)
                    tic;        % Comando para iniciar temporizador (para medir tiempos luego
                    % Cargar imagen o tomar imagen de camara segun el modo
                    if(app.chkboxDebugconImagenes.Value)
                        I = imread(app.dropdownImagen.Value);
                    else
                        I = snapshot(app.camara);
                    end
                    
                    if(app.CentrodeMesaCheckBox.Value)
                        center(1) = size(I,2)/2;
                        center(2) = size(I,1)/2;
                    else
                        center(1) = app.CentroYSpinner.Value/app.LongitudSpinner.Value * size(I,1);
                        center(2) = app.CentroXSpinner.Value/app.AnchoSpinner.Value * size(I,2);
                    end
                    
                    % Aplicacion de transformada obtenida en calibracion
                    Itrans = imwarp(I, app.tform, 'OutputView', app.outviewref);
                    % Escala de grises y filtro canny
                    Itrans = rgb2gray(Itrans);
                    CalliBW = edge(Itrans,'Canny',0.5);
                    % Mostrar imagen con y sin mascara para depuracion
                    if(app.chkboxMostrarProcesoPose.Value)
                        figure(14)
                        subplot(1,2,1)
                        imshow(CalliBW)
                        title('Imagen luego de aplicar filtro Canny')
                        CalliBW = logical(app.obstacleMask.*CalliBW);
                        subplot(1,2,2)
                        imshow(CalliBW)
                        title('Imagen luego de aplicar máscara')
                    end
                    % Aplicar mascara en caso no se encuentre depuraciona
                    % activa
                    CalliBW = logical(app.obstacleMask.*CalliBW);
                    
                    %% Limpiar contornos extra
                    % Rellenar contornos cerrados
                    CalliBW = imfill(CalliBW,'holes');
                    % Obtener perimetro, area y listado de pixeles de contornos
                    s = regionprops(CalliBW, 'Area', 'Perimeter', 'PixelIdxList', 'PixelList', 'Circularity', 'Centroid');
                    % Convertir areas, perimetros y contornos de pixeles a
                    % formatos convenientes (listas o lista de celdas)
                    areas = cat(1, s.Area);
                    perimeters = cat(1, s.Perimeter);
                    circularities = cat(1, s.Circularity);
                    disp(circularities)
                    pixellists = {s.PixelList};
                    
                    % Por cada contorno
                    for i = 1:length(areas)
                        % Si la proporcion es menor a lo establecido (1.25
                        % por defecto)
                        if(areas(i)/perimeters(i) < area_per_proportion | areas(i) < area_min)
                            % Tomar el listado de pixeles
                            pixels = cell2mat(pixellists(i));
                            % Y convertir los pixeles en negro
                            for i2 = 1:size(pixels,1)
                                CalliBW(pixels(i2,2), pixels(i2,1)) = 0;
                            end
                        end
                    end
                    
                    for i = 1:size(circularities)
                        if(circularities(i) > app.editfieldValorCircularidadACO.Value)
                            pixels = cell2mat(pixellists(i));
                            for i2 = 1:size(pixels,1)
                                CalliBW(pixels(i2,2), pixels(i2,1)) = 0;
                            end
                        end
                    end
                    
                    % Si se desea mostrar el proceso, desplegar imagen
                    if(app.chkboxMostrarProcesoPose.Value)
                        figure(15);
                        imshow(CalliBW)
                        title('Imagen limpia')
                    end
                    
                    %% Procesamiento de identificadores
                    % Obtener mapa con puntos de las boundingboxes de los
                    % contornos
                    [contours, ~] = getContours(CalliBW);
                    % Inicializar variable para ids, angulos y centroides
                    NumericIDs = zeros(1, length(contours));
                    Angles= zeros(1, length(contours) );
                    centroids = zeros(length(contours), 2);
                    app.centroidsMod = zeros(length(app.contours), 2);
                    
                    % Por cada boundingbox encontrada, calcular el
                    % centroide con el promedio de las coordenadas
                    for i = 1:length(contours)
                        bb = contours(string(i));
                        centroids(i,1) = mean(bb(:,1));
                        centroids(i,2) = mean(bb(:,2));
                        app.centroidsMod(i,1) = app.centroids(i, 1) - center(1);
                        app.centroidsMod(i,2) = app.centroids(i, 2) - center(2);
                    end
                    
                    % Si modo depuracion, colocar centroides en la imagen
                    % anterior con color azul y radio 10px
                    if(app.chkboxMostrarProcesoPose.Value)
                        for i = 1:length(contours)
                            viscircles(centroids(i,:), 10,'Color','Blue');
                            text(centroids(i,1),centroids(i,2),string(i),'Color','Blue');
                        end
                    end
                    
                    pivotValues = [];
                    otherValues = containers.Map();
                    % Para cada contorno (identificador) encontrado
                    for i = 1:length(contours)
                        fixed_points=[0 0; size(I,1) 0; size(I,1) size(I,1); 0 size(I,1)]; %puntos iniciales en la imagen original
                        moving_points = contours(num2str(i));   % Puntos de la "Bounding box" que encierra el identificador
                        
                        % Calcular el angulo inicial
                        angle = atan2(abs(moving_points(2,2)-moving_points(1,2)), abs(moving_points(2,1)-moving_points(1,1)));
                        
                        % Crear transformada para extraerlo de la imagen
                        codeTForm = fitgeotrans(moving_points,fixed_points,'projective');
                
                        codeOutViewRef = imref2d([size(Itrans,1), size(Itrans,1)]);
                        code = imwarp(Itrans, codeTForm, 'OutputView', codeOutViewRef);
                        % Cuadricula de los sectores de los codigos
                        corners = [1/4, 1/4; 2/4, 1/4; 3/4, 1/4;
                                   1/4, 2/4; 2/4, 2/4; 3/4, 2/4;
                                   1/4, 3/4; 2/4, 3/4; 3/4, 3/4;].*flip(size(code));
                        
                        % Si la proporcion entre el maximo y cada esquina
                        % es menor a el umbra establecido (1.25 en este
                        % caso), se corrige el angulo y rota la imagen
                        if(code(corners(3,2),corners(3,1)) > pivotPropThresh)
                            code = imrotate(code, 90);
                        elseif(code(corners(7,2),corners(7,1)) > pivotPropThresh)
                            angle = angle + pi;
                            code = imrotate(code, -90);
                        elseif(code(corners(9,2),corners(9,1)) > pivotPropThresh)
                            angle = angle + 3*pi/2;
                            code = imrotate(code, 180);
                        else
                            angle = angle + pi/2;
                        end
                        
                        % Limitar el angulo a 360 grados
                        if(angle > 2*pi)
                            angle = angle - 2*pi;
                        end
                        
                        % Convertir a grados
                        Angles(i) = rad2deg(angle);
                        
                        % Variable para el string binario
                        NumericID = zeros(1, 8);
                        % Por cada sector en la cuadricula, menos el pivote
                        for k = 1:length(corners)-1
                            % Clasificar el color segun el umbral en la
                            % interfaz
                            indiceColorInf_x = corners(k+1,2)-app.editfieldAreadeMuestreo.Value;
                            indiceColorSup_x = corners(k+1,2)+app.editfieldAreadeMuestreo.Value;
                            indiceColorInf_y = corners(k+1,1)-app.editfieldAreadeMuestreo.Value;
                            indiceColorSup_y = corners(k+1,1)+app.editfieldAreadeMuestreo.Value;
                            colorActual = code(indiceColorInf_x:indiceColorSup_x , indiceColorInf_y:indiceColorSup_y);
                            if(mean(colorActual) > highThresh)
                                NumericID(k) = 1;
                            else
                                NumericID(k) = 0;
                            end
                        end
                        %Convertir de binario a decimal y guardar
                        NumericID = bi2de(NumericID, 'right-msb');
                        NumericIDs(i) = NumericID;
                        
                        % Si modo depuracion, mostrar recortes en un grid
                        % con valores de cada sector y el punto tomado
                        if(app.chkboxMostrarProcesoPose.Value)
                            figure(24)
                            sgtitle('Recorte de identificadores encontrados')
                            subplot(2, 3, i)
                            save('a.mat','code','-append')
                            imshow(code)
                            dots = [];
                            for k = 1:length(corners)
                                dots = [dots;code(corners(k, 2), corners(k,1))];
                                viscircles(corners(k,:), rad(k),'EdgeColor','b');
                                text(corners(k,1),corners(k,2),string(code(corners(k,2),corners(k,1))));
                            end
                            viscircles(corners, rad,'EdgeColor','b');
                            title(strcat('Código número ', ' ',string(i)))
                        end
                    end
                    % Si modo debug, mostrar todos los contornos, sus
                    % centroides y orientaciones respectivas
                    if(app.chkboxDesplegarResultados.Value)
                        figure(1);
                        imshow(Itrans, 'parent', self.handlePose)
                        hold on
                        for i = 1:length(contours)
                            text(centroids(i,1), centroids(i,2)+30, strcat('ID : ', num2str(NumericIDs(i))),'Color','red','FontSize',10)
                            text(centroids(i,1), centroids(i,2)+60, strcat('Ángulo : ', num2str(Angles(i))), 'Color','red','FontSize',10)
                            text(centroids(i,1), centroids(i,2)+90, strcat('Centroide : (', num2str(centroids(i,1) * app.LongitudSpinner.Value / size(Itrans, 2)), ',', num2str(centroids(i,2) * app.AnchoSpinner.Value / size(Itrans, 1)), ')'), 'Color','red','FontSize',10)
                            viscircles(centroids(i,:), 5);
%                             scatter(moving_points(1,1),moving_points(1,2),25,[1,0,0],'filled');
%                             scatter(moving_points(2,1),moving_points(2,2),25,[0,1,0],'filled');
%                             scatter(moving_points(3,1),moving_points(3,2),25,[0,0,1],'filled');
%                             scatter(moving_points(4,1),moving_points(4,2),25,[1,0,1],'filled');
                        end
                    end
                    
                    % Si comunicacion TCP habilitada
                    if(app.chkboxHabilitarComunicacionTCP.Value)
                        writeString = '';
                        % Por cada contorno *encontrado*
                        for i = 1:length(contours)
                            % Si hay ips en el listado
                            if(app.listaIPs.length)
                                % Si el id del contorno es parte del
                                % listado de ips
                                if(app.listaIPs.isKey(NumericIDs(i)))
                                    % Concatenar pose separado por ','
                                    writeString = '';
                                    writeString = strcat(writeString,num2str(centroids(i,1)*app.LongitudSpinner.Value/size(Itrans, 1)), ',');
                                    writeString = strcat(writeString,num2str(centroids(i,2)*app.AnchoSpinner.Value/size(Itrans, 1)), ',');
                                    writeString = strcat(writeString,num2str(Angles(i)));
                                    % Abrir socket y enviar
                                    socket =  tcpclient(app.listaIPs(NumericIDs(i),string(app.editfieldPuerto.Value),'Timeout',responseTimeout,"ConnectTimeout",connectionTimeout));
                                    write(socket, writeString);
                                end
                            else
                                writeString = strcat(writeString,num2str(centroids(i,1)*app.LongitudSpinner.Value/size(Itrans, 1)), ',');
                                writeString = strcat(writeString,num2str(centroids(i,2)*app.AnchoSpinner.Value/size(Itrans, 1)), ',');
                                writeString = strcat(writeString,num2str(Angles(i),';'));
                            end
                        end
                    end
                        
                    % Si comunicacion UDP habilitada
                    if(app.chkboxHabilitarComunicacionUDP.Value)
                        % Por cada contorno
                        for i = 1:length(contours)
                            % Si el listado tiene objetos
                            if(app.listaIPs.length)
                                % Si el id del contorno esta en el listado
                                if(app.listaIPs.isKey(NumericIDs(i)))
                                    % Concatenar pose separado por ','s
                                    writeString = app.editfieldPalabradeInicio.Value;
                                    writeString = strcat(writeString,num2str(round(centroids(i,1)*app.LongitudSpinner.Value/size(Itrans, 2),1)), ',');
                                    writeString = strcat(writeString,num2str(round(centroids(i,2)*app.AnchoSpinner.Value/size(Itrans, 1),1)), ',');
                                    writeString = strcat(writeString,num2str(round(Angles(i),1)));
                                    % Escribir a travez del socket a la ip
                                    % respectiva
                                    write(app.u,writeString,"char",app.listaIPs(NumericIDs(i)),app.editfieldPuerto.Value);
                                    disp(writeString)
                                end
                            end
                        end
                        
                    end
    
                    % Para temprizador de tiempo de ejecucion
                    time = toc;
                    % Si se desea guardar tiempos, concatenar al archivo
                    % 'tiempos.mat' y en la variable 'times'
                    if(app.chkboxGuardarTiempos.Value)
                        load('Tiempos.mat', 'times')
                        times = [times, time];
                        save('Tiempos.mat', 'times')
                        delete('times');
                    end             
                    save('Valores.mat', 'pivotValues', 'otherValues');
                catch ME
                    % Si hay algun error, no para la ejecucion sino mostrar
                    % mensaje de error
                    disp(ME.message)
                    % Y mostrar en el centro de la imagen que hubo un error
                    figure(1);
                    text(size(I,1)/2, size(I,2)/2, 'Error en toma de Pose, intentando de nuevo')
                end
                
                % Si el buclo no esta activo, parar el funcionamiento
                if(~app.chkboxBucle.Value)
                    app.funcionando = false;
                end
            end
        end

        % Button pushed function: btnPararTomadePose
        function btnPararTomadePosePushed(app, event)
            % Cambiar estado de variable global para detener el
            % funcionamiento de toma de pose y desplegar mensaje diciendolo
            app.funcionando = false
            app.lblDebug.Text = 'Programa Parado';
        end

        % Button pushed function: btnGenerarCodigo
        function btnGenerarCodigoPushed(app, event)
            % Obtener numero de la interfaz
            codigoID = app.txtfldNumerodecodigo.Value;
            % Ingresarlo a la funcion para crear codigos
            codigo = CreadorCodigos(codigoID);
            % Limpiar figura 1
            figure(1); clf
            % Obtener handle de figura
            app.handleCodigo = gcf;
            % Mostrar imagen
            imshow(codigo);
        end

        % Button pushed function: btnGuardarCodigo
        function btnGuardarCodigoPushed(app, event)
            % Con el handle obtenido, guardar imagen
            saveas(app.handleCodigo, strcat(app.txtfldNombredelArchivo.Value,'.jpg'));
            % Desplegar mensaje de exito en interfaz
            app.lblerrorGeneracion.Text = {'Código','Guardado'};
        end

        % Button pushed function: btnSeleccionManual
        function btnSeleccionManualPushed(app, event)
            % Activar variable global para saber al momento de calibrar,
            % que se desean estos puntos
            app.manual = true;
            % Leer imagen u obtenerla de la camara
            if(app.chkboxDebugconImagenes.Value)
                I = imread(app.dropdownImagen.Value);
            else
                I = snapshot(app.camara);
            end
            % Abrir la herramienta cpselect y guardar los puntos finales
            [app.manual_points, ~] = cpselect(I, I,'Wait',true);
        end

        % Button pushed function: btnLoadCalli
        function btnLoadCalliButtonPushed(app, event)
            % Cargar datos de 'Datos.mat'
            load('Datos.mat','obstacleMask', 'tform', 'outviewref', 'camara')
            % Mostrar en interfaz
            app.lblErrorCalli.Text = 'Datos Recuperados de "Datos.mat"';
            % Asignar variables locales a variables globales
            app.obstacleMask = obstacleMask;
            app.tform = tform;
            app.outviewref = outviewref;
            app.camara = camara;
            
            % Leer imagen u obtenerla de la camara
            if(app.chkboxDebugconImagenes.Value)
                I = imread(app.dropdownImagen.Value);
            else
                I = snapshot(app.camara);
            end
            % Aplicar transformada
            Itrans = imwarp(I, app.tform, 'OutputView', app.outviewref);
            
            % Limiar figura 1 y mostrar la calibracion cargada
            figure(1); clf
            subplot(1, 2, 1)
            imshow(Itrans)
            title('Imagen Calibrada','FontWeight','normal')
            subplot(1, 2, 2)
            imshow(app.obstacleMask)
            title('Máscara de Obstáculos encontrados','FontWeight','normal')
            % Habilitar boton de toma de pose
            app.btnTomadePose.Enable = true;
            app.btnCalibrar.Enable = true;
            app.chkboxModoACO.Enable = true;
        end

        % Button pushed function: btnSaveCalli
        function btnSaveCalliButtonPushed(app, event)
            obstacleMask = app.obstacleMask;
            tform = app.tform;
            outviewref = app.outviewref;
            camara = app.camara;
            save('Datos.mat','obstacleMask', 'tform', 'outviewref', 'camara')
            app.lblErrorCalli.Text = 'Datos Guardados en "Datos.mat"';
        end

        % Value changed function: chkboxDebugconImagenes
        function chkboxDebugconImagenesValueChanged(app, event)
            % Leer valor al que se cambio
            value = app.chkboxDebugconImagenes.Value;
            if(value)
                % Si es 1, habilitar dropdown para seleccionar imagen
                app.dropdownImagen.Enable = true;
                % Leer extension deseada
                ext = app.txtfldExtension.Value;
                % Leer todos los archivos en el directorio actual con dicha
                % extension
                list = dir(strcat('*.',ext));
                % Hacer una lista con celdas
                names = {list.name}';
                % Agregar los items a las opciones del dropdown
                app.dropdownImagen.Items = names;
                % Habilitar botones de calibrar y calibracion manual
                app.btnCalibrar.Enable = true;
                app.btnSeleccionManual.Enable = true;
            else
                % Si es 0, deshabilitar el dropdown
                app.dropdownImagen.Enable = false;
            end
        end

        % Button pushed function: btnEliminarIP
        function btnEliminarIPButtonPushed(app, event)
            % Si el valor de ID existe, eliminarlo, de lo contrario,
            % desplegar que no es existente
            if(isKey(app.listaIPs,app.spnIDdeRobot.Value))
                remove(app.listaIPs,app.spnIDdeRobot.Value);
            else
                app.lblErroresCom.Text = "ID no registrado";
            end
            % Obtener lista de ips registradas y desplegarlas en el listbox
            ips = {};
            for i = keys(app.listaIPs)
                ips(length(ips)+1) = {app.listaIPs(double(string(i)))};
            end
            app.listboxIPs.Items = ips;
        end

        % Button pushed function: btnAgregarIP
        function btnAgregarIPButtonPushed(app, event)
            % Si existe valor en ip, guardarlo, de lo contrario mostrar
            % mensaje de error
            if(app.editfieldDirreccionIP.Value)
                app.listaIPs(app.spnIDdeRobot.Value) = app.editfieldDirreccionIP.Value;
            else
                app.lblErroresCom.Text = "Error, no se especificó dirrección IP";
            end
            
            % Actualizar listado de ips registradas
            ips = {};
            for i = keys(app.listaIPs)
                ips(length(ips)+1) = {app.listaIPs(double(string(i)))};
            end
            app.listboxIPs.Items = ips;
            % Abrir socket udp y guardarlo en variable global
            app.u = udpport('byte',"IPV4");
        end

        % Button pushed function: EnviodeMapaButton
        function EnviodeMapaButtonPushed(app, event)
            mapSize = floor(sqrt(size(keys(app.mapa),2)));
            puntosDePrueba = 10;
            keySet = keys(app.listaIPs);
            keySet = cell2mat(keySet(1));
            x = 1;
            y = 1;
            for i = 1:mapSize^2
                pause(0.1)
                centroide = round(app.mapa(strcat(string(x),string(y))).*[app.AnchoSpinner.Value, app.LongitudSpinner.Value]./size(app.obstacleMask),1);
                writeString = 'C,';
                writeString = strcat(writeString, string(i-1),',');
                writeString = strcat(writeString, string(centroide(1)),',');
                writeString = strcat(writeString, string(centroide(2)));
                write(app.u,writeString,"char",app.listaIPs(keySet(1)),app.editfieldPuerto.Value);
                disp(writeString)
                if(x == mapSize)
                    x = 0;
                    y = y + 1;
                end
                x = x + 1;
            end
            
            x = 1;
            y = 1;
            send = true;
            for i = 1:mapSize^2
                pause(0.1)
                % Conexion en X. Borde en X de origen
                if(x < mapSize)
                    send = true;
                    centroide1 = app.mapa(strcat(string(x),string(y)));
                    centroide2 = app.mapa(strcat(string(x+1),string(y)));
                    distancia = [norm(centroide1(1) - centroide2(1)), norm(centroide1(2) - centroide2(2))];
                    for j = 1:puntosDePrueba
                        puntoPrueba = floor(centroide1 + j.*distancia./puntosDePrueba);
                        if ~(app.obstacleMask(puntoPrueba(2),puntoPrueba(1)))
                            send = false;
                        end
                    end
                    if(send)
                        writeString = 'G,';
                        writeString = strcat(writeString, string(i-1),',');
                        writeString = strcat(writeString, string(i),',');
                        write(app.u,writeString,"char",app.listaIPs(keySet(1)),app.editfieldPuerto.Value);
                        disp(writeString)
                    else
                        disp(strcat('Obstaculo entre nodos: ', string(i-1), ',',string(i)));
                    end
                end
                % Conexion en Y. Bordes en Y de origen
                if(y < mapSize)
                    send = true;
                    centroide1 = app.mapa(strcat(string(x),string(y)));
                    centroide2 = app.mapa(strcat(string(x),string(y+1)));
                    distancia = [norm(centroide1(1) - centroide2(1)), norm(centroide1(2) - centroide2(2))];
                    for j = 1:puntosDePrueba
                        puntoPrueba = floor(centroide1 + j.*distancia./puntosDePrueba);
                        if ~(app.obstacleMask(puntoPrueba(2),puntoPrueba(1)))
                            send = false;
                        end
                    end
                    if(send)
                        writeString = 'G,';
                        writeString = strcat(writeString, string(i-1),',');
                        writeString = strcat(writeString, string(i+mapSize-1),',');
                        write(app.u,writeString,"char",app.listaIPs(keySet(1)),app.editfieldPuerto.Value);
                        disp(writeString)
                    else
                        disp(strcat('Obstaculo entre nodos: ', string(i-1), ',',string(i+mapSize-1)));
                    end
                end
                % Conexion en diagonal. Borde en X final
                if(x < mapSize && y < mapSize)
                    send = true;
                    centroide1 = app.mapa(strcat(string(x),string(y)));
                    centroide2 = app.mapa(strcat(string(x+1),string(y+1)));
                    distancia = [norm(centroide1(1) - centroide2(1)), norm(centroide1(2) - centroide2(2))];
                    for j = 1:puntosDePrueba
                        puntoPrueba = floor(centroide1 + j.*distancia./puntosDePrueba);
                        if ~(app.obstacleMask(puntoPrueba(2),puntoPrueba(1)))
                            send = false;
                        end
                    end
                    if(send)
                        writeString = 'G,';
                        writeString = strcat(writeString, string(i-1),',');
                        writeString = strcat(writeString, string(i+mapSize),',');
                        write(app.u,writeString,"char",app.listaIPs(keySet(1)),app.editfieldPuerto.Value);
                        disp(writeString)
                    else
                        disp(strcat('Obstaculo entre nodos: ', string(i-1), ',',string(i+mapSize)));
                    end
                end
                % Conexion en diagonal opuesta. Borde en Y final
                if(y > 1 && x < mapSize)
                    send = true;
                    centroide1 = app.mapa(strcat(string(x),string(y)));
                    centroide2 = app.mapa(strcat(string(x+1),string(y-1)));
                    distancia = [-norm(centroide1(1) - centroide2(1)), norm(centroide1(2) - centroide2(2))];
                    for j = 1:puntosDePrueba
                        puntoPrueba = floor(centroide1 + j.*distancia./puntosDePrueba);
                        if ~(app.obstacleMask(puntoPrueba(2),puntoPrueba(1)))
                            send = false;
                        end
                    end
                    if(send)
                        writeString = 'G,';
                        writeString = strcat(writeString, string(i-1),',');
                        writeString = strcat(writeString, string(i-mapSize),',');
                        write(app.u,writeString,"char",app.listaIPs(keySet(1)),app.editfieldPuerto.Value);
                        disp(writeString)
                    else
                        disp(strcat('Obstaculo entre nodos: ', string(i-1), ',',string(i-mapSize)));
                    end
                end
                if(x == mapSize)
                    x = 0;
                    y = y + 1;
                end
                x = x + 1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HerramientadeCVparaRobticaSwarmUIFigure = uifigure('Visible', 'off');
            app.HerramientadeCVparaRobticaSwarmUIFigure.Name = 'Herramienta de CV para Robótica Swarm';
            app.HerramientadeCVparaRobticaSwarmUIFigure.Icon = 'logo002.png';
            app.TabGroup = uitabgroup(app.HerramientadeCVparaRobticaSwarmUIFigure);
            app.CalibracinTab = uitab(app.TabGroup);
            app.CalibracinTab.Title = 'Calibración';
            app.btnCalibrar = uibutton(app.CalibracinTab, 'push');
            app.btnCalibrar.ButtonPushedFcn = createCallbackFcn(app, @btnCalibrarPushed, true);
            app.btnCalibrar.Enable = 'off';
            app.btnCalibrar.Text = 'Calibrar';
            app.btnConnectar = uibutton(app.CalibracinTab, 'push');
            app.btnConnectar.ButtonPushedFcn = createCallbackFcn(app, @btnConnectarPushed, true);
            app.btnConnectar.Text = 'Connectar';
            app.dropdownCamara = uidropdown(app.CalibracinTab);
            app.dropdownCamara.ValueChangedFcn = createCallbackFcn(app, @CamaraDropDownValueChanged, true);
            app.lblCamara = uilabel(app.CalibracinTab);
            app.lblCamara.Text = 'Cámara Web';
            app.btnSeleccionManual = uibutton(app.CalibracinTab, 'push');
            app.btnSeleccionManual.ButtonPushedFcn = createCallbackFcn(app, @btnSeleccionManualPushed, true);
            app.btnSeleccionManual.Enable = 'off';
            app.btnSeleccionManual.Text = 'Selección Manual';
            app.imgLogo2 = uiimage(app.CalibracinTab);
            app.imgLogo2.ImageSource = 'logo002.png';
            app.btnSaveCalli = uibutton(app.CalibracinTab, 'push');
            app.btnSaveCalli.ButtonPushedFcn = createCallbackFcn(app, @btnSaveCalliButtonPushed, true);
            app.btnSaveCalli.Enable = 'off';
            app.btnSaveCalli.Text = {'Guardar última '; 'calibración'; ''};
            app.lblErrorCalli = uilabel(app.CalibracinTab);
            app.lblErrorCalli.Text = '';
            app.btnLoadCalli = uibutton(app.CalibracinTab, 'push');
            app.btnLoadCalli.ButtonPushedFcn = createCallbackFcn(app, @btnLoadCalliButtonPushed, true);
            app.btnLoadCalli.Text = {'Cargar última '; 'calibración'; ''};
            app.LongitudSpinnerLabel = uilabel(app.CalibracinTab);
            app.LongitudSpinnerLabel.Text = 'Longitud';
            app.LongitudSpinner = uispinner(app.CalibracinTab);
            app.LongitudSpinner.Step = 0.01;
            app.LongitudSpinner.Limits = [0 Inf];
            app.LongitudSpinner.Value = 107.5;
            app.AnchoSpinnerLabel = uilabel(app.CalibracinTab);
            app.AnchoSpinnerLabel.Text = 'Ancho';
            app.AnchoSpinner = uispinner(app.CalibracinTab);
            app.AnchoSpinner.Step = 0.01;
            app.AnchoSpinner.Limits = [0 Inf];
            app.AnchoSpinner.Value = 80;
            app.chkboxModoACO = uicheckbox(app.CalibracinTab);
            app.chkboxModoACO.Enable = 'off';
            app.chkboxModoACO.Text = 'Modo ACO';
            app.chkboxGrid = uicheckbox(app.CalibracinTab);
            app.chkboxGrid.Text = 'Grid';
            app.EnviodeMapaButton = uibutton(app.CalibracinTab, 'push');
            app.EnviodeMapaButton.ButtonPushedFcn = createCallbackFcn(app, @EnviodeMapaButtonPushed, true);
            app.EnviodeMapaButton.Enable = 'off';
            app.EnviodeMapaButton.Text = 'Envio de Mapa';
            app.CentrodeMesaCheckBox = uicheckbox(app.CalibracinTab);
            app.CentrodeMesaCheckBox.Text = 'Centro de Mesa';
            app.OrigenyLabel = uilabel(app.CalibracinTab);
            app.OrigenyLabel.Text = 'Origen (y)';
            app.CentroYSpinner = uispinner(app.CalibracinTab);
            app.CentroYSpinner.Limits = [0 Inf];
            app.OrigenxLabel = uilabel(app.CalibracinTab);
            app.OrigenxLabel.Text = 'Origen (x)';
            app.CentroXSpinner = uispinner(app.CalibracinTab);
            app.CentroXSpinner.Limits = [0 Inf];
            app.TomadePoseTab = uitab(app.TabGroup);
            app.TomadePoseTab.Title = 'Toma de Pose';
            app.btnPararTomadePose = uibutton(app.TomadePoseTab, 'push');
            app.btnPararTomadePose.ButtonPushedFcn = createCallbackFcn(app, @btnPararTomadePosePushed, true);
            app.btnPararTomadePose.Enable = 'off';
            app.btnPararTomadePose.Text = 'Parar Toma de Pose';
            app.imgLogo1 = uiimage(app.TomadePoseTab);
            app.imgLogo1.ImageSource = 'logo002.png';
            app.btnTomadePose = uibutton(app.TomadePoseTab, 'push');
            app.btnTomadePose.ButtonPushedFcn = createCallbackFcn(app, @btnTomadePosePushed, true);
            app.btnTomadePose.Enable = 'off';
            app.btnTomadePose.Text = 'Toma de Pose';
            app.lblDebug = uilabel(app.TomadePoseTab);
            app.lblDebug.Text = '';
            app.chkboxBucle = uicheckbox(app.TomadePoseTab);
            app.chkboxBucle.Text = 'Bucle';
            app.chkboxBucle.Value = true;
            app.chkboxGuardarTiempos = uicheckbox(app.TomadePoseTab);
            app.chkboxGuardarTiempos.Text = {'Guardar Tiempos'; ''};
            app.chkboxDesplegarResultados = uicheckbox(app.TomadePoseTab);
            app.chkboxDesplegarResultados.Text = 'Desplegar Imagen con Resultados';
            app.btnEnvodeDatos = uibutton(app.TomadePoseTab, 'push');
            app.btnEnvodeDatos.Enable = 'off';
            app.btnEnvodeDatos.Text = 'Envío de Datos';
            app.chkboxEnvioentoma = uicheckbox(app.TomadePoseTab);
            app.chkboxEnvioentoma.Text = 'Envío en toma';
            app.GeneracindeCodigosTab = uitab(app.TabGroup);
            app.GeneracindeCodigosTab.Title = 'Generación de Códigos';
            app.NmerodecdigoEditFieldLabel = uilabel(app.GeneracindeCodigosTab);
            app.NmerodecdigoEditFieldLabel.Text = 'Número de código';
            app.txtfldNumerodecodigo = uieditfield(app.GeneracindeCodigosTab, 'numeric');
            app.txtfldNumerodecodigo.Limits = [0 255];
            app.NombredelArchivoEditFieldLabel = uilabel(app.GeneracindeCodigosTab);
            app.NombredelArchivoEditFieldLabel.Text = 'Nombre del Archivo';
            app.txtfldNombredelArchivo = uieditfield(app.GeneracindeCodigosTab, 'text');
            app.txtfldNombredelArchivo.Value = 'Codigo_generado';
            app.btnGenerarCodigo = uibutton(app.GeneracindeCodigosTab, 'push');
            app.btnGenerarCodigo.ButtonPushedFcn = createCallbackFcn(app, @btnGenerarCodigoPushed, true);
            app.btnGenerarCodigo.Text = 'Generar Código';
            app.btnGuardarCodigo = uibutton(app.GeneracindeCodigosTab, 'push');
            app.btnGuardarCodigo.ButtonPushedFcn = createCallbackFcn(app, @btnGuardarCodigoPushed, true);
            app.btnGuardarCodigo.Text = 'Guardar Código';
            app.lblNota = uilabel(app.GeneracindeCodigosTab);
            app.lblNota.Text = 'El archivo se guardará en formato jpg';
            app.lblerrorGeneracion = uilabel(app.GeneracindeCodigosTab);
            app.lblerrorGeneracion.Text = '';
            app.lblLogo3 = uiimage(app.GeneracindeCodigosTab);
            app.lblLogo3.ImageSource = 'logo002.png';
            app.Comunicacion = uitab(app.TabGroup);
            app.Comunicacion.Title = 'Comunicación';
            app.DirreccinIPEditFieldLabel = uilabel(app.Comunicacion);
            app.DirreccinIPEditFieldLabel.Text = 'Dirrección IP';
            app.editfieldDirreccionIP = uieditfield(app.Comunicacion, 'text');
            app.btnEliminarIP = uibutton(app.Comunicacion, 'push');
            app.btnEliminarIP.ButtonPushedFcn = createCallbackFcn(app, @btnEliminarIPButtonPushed, true);
            app.btnEliminarIP.Text = 'Eliminar IP';
            app.IDdeRobotSpinnerLabel = uilabel(app.Comunicacion);
            app.IDdeRobotSpinnerLabel.Text = 'ID de Robot';
            app.spnIDdeRobot = uispinner(app.Comunicacion);
            app.spnIDdeRobot.Limits = [0 255];
            app.chkboxHabilitarComunicacionTCP = uicheckbox(app.Comunicacion);
            app.chkboxHabilitarComunicacionTCP.Text = 'Habilitar Comunicación TCP';
            app.lblErroresCom = uilabel(app.Comunicacion);
            app.lblErroresCom.Text = {''; ''};
            app.PuertogeneralEditFieldLabel = uilabel(app.Comunicacion);
            app.PuertogeneralEditFieldLabel.Text = 'Puerto (general)';
            app.editfieldPuerto = uieditfield(app.Comunicacion, 'numeric');
            app.editfieldPuerto.Limits = [0 65535];
            app.lblMensaje = uilabel(app.Comunicacion);
            app.lblMensaje.Text = 'De desear que se envien a una misma IP, únicamente llenar IP y puerto (sin precionar el botón)';
            app.chkboxHabilitarComunicacionUDP = uicheckbox(app.Comunicacion);
            app.chkboxHabilitarComunicacionUDP.Text = 'Habilitar Comunicación UDP';
            app.chkboxHabilitarComunicacionUDP.Value = true;
            app.IPsListBoxLabel = uilabel(app.Comunicacion);
            app.IPsListBoxLabel.Enable = 'off';
            app.IPsListBoxLabel.Text = 'IPs';
            app.listboxIPs = uilistbox(app.Comunicacion);
            app.listboxIPs.Enable = 'off';
            app.btnAgregarIP = uibutton(app.Comunicacion, 'push');
            app.btnAgregarIP.ButtonPushedFcn = createCallbackFcn(app, @btnAgregarIPButtonPushed, true);
            app.btnAgregarIP.Text = 'Agregar IP';
            app.imgLogo2_2 = uiimage(app.Comunicacion);
            app.imgLogo2_2.ImageSource = 'logo002.png';
            app.PalabradeInicioEditFieldLabel = uilabel(app.Comunicacion);
            app.PalabradeInicioEditFieldLabel.Text = {'Palabra'; 'de Inicio'};
            app.editfieldPalabradeInicio = uieditfield(app.Comunicacion, 'text');
            app.editfieldPalabradeInicio.Value = 'P,';
            app.DebugTab = uitab(app.TabGroup);
            app.DebugTab.Title = 'Debug';
            app.chkboxDebugconImagenes = uicheckbox(app.DebugTab);
            app.chkboxDebugconImagenes.ValueChangedFcn = createCallbackFcn(app, @chkboxDebugconImagenesValueChanged, true);
            app.chkboxDebugconImagenes.Text = 'Debug con Imagenes';
            app.ImagenDropDownLabel = uilabel(app.DebugTab);
            app.ImagenDropDownLabel.Text = 'Imagen';
            app.dropdownImagen = uidropdown(app.DebugTab);
            app.dropdownImagen.Items = {'Option 1'};
            app.ExtensinEditFieldLabel = uilabel(app.DebugTab);
            app.ExtensinEditFieldLabel.Text = 'Extensión';
            app.txtfldExtension = uieditfield(app.DebugTab, 'text');
            app.txtfldExtension.Value = 'png';
            app.chkboxMostrarProcesoMascara = uicheckbox(app.DebugTab);
            app.chkboxMostrarProcesoMascara.Text = 'Mostrar proceso de máscara';
            app.chkboxMostrarProcesoPose = uicheckbox(app.DebugTab);
            app.chkboxMostrarProcesoPose.Text = 'Mostrar proceso de toma de pose';
            app.ValordePivoteEditFieldLabel = uilabel(app.DebugTab);
            app.ValordePivoteEditFieldLabel.Text = 'Valor de Pivote';
            app.editfieldValordePivote = uieditfield(app.DebugTab, 'numeric');
            app.editfieldValordePivote.Limits = [0 255];
            app.editfieldValordePivote.Value = 140;
            app.ValordeGrisEditFieldLabel = uilabel(app.DebugTab);
            app.ValordeGrisEditFieldLabel.Text = 'Valor de Gris';
            app.editfieldValordeGris = uieditfield(app.DebugTab, 'numeric');
            app.editfieldValordeGris.Limits = [0 255];
            app.editfieldValordeGris.Value = 60;
            app.imgLogo2_3 = uiimage(app.DebugTab);
            app.imgLogo2_3.ImageSource = 'logo002.png';
            app.ValorCircularidadACOEditFieldLabel = uilabel(app.DebugTab);
            app.ValorCircularidadACOEditFieldLabel.Text = {'Valor Circularidad'; '(ACO)'};
            app.editfieldValorCircularidadACO = uieditfield(app.DebugTab, 'numeric');
            app.editfieldValorCircularidadACO.Limits = [0 Inf];
            app.editfieldValorCircularidadACO.Value = 1;
            app.readeMuestreoEditFieldLabel = uilabel(app.DebugTab);
            app.readeMuestreoEditFieldLabel.Text = 'Área de Muestreo';
            app.editfieldAreadeMuestreo = uieditfield(app.DebugTab, 'numeric');
            app.editfieldAreadeMuestreo.Limits = [0 Inf];
            app.editfieldAreadeMuestreo.Value = 4;
            % Show the figure after all components are created
            app.HerramientadeCVparaRobticaSwarmUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_TestBed

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HerramientadeCVparaRobticaSwarmUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HerramientadeCVparaRobticaSwarmUIFigure)
        end
    end
end